classdef handfitting_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PlotButton                     matlab.ui.control.Button
        LoadButton                     matlab.ui.control.Button
        PerturbationMagnitudeDropDown  matlab.ui.control.DropDown
        PerturbationMagnitudeDropDownLabel  matlab.ui.control.Label
        PerturbationDirectionDropDown  matlab.ui.control.DropDown
        PerturbationDirectionDropDownLabel  matlab.ui.control.Label
        ParticipantDropDown            matlab.ui.control.DropDown
        ParticipantDropDownLabel       matlab.ui.control.Label
        ReconstructionTypeDropDown     matlab.ui.control.DropDown
        ReconstructionTypeDropDownLabel  matlab.ui.control.Label
        Slider_2                       matlab.ui.control.Slider
        Slider_2Label                  matlab.ui.control.Label
        k_d2Slider                     matlab.ui.control.Slider
        k_d2SliderLabel                matlab.ui.control.Label
        k_v2Slider                     matlab.ui.control.Slider
        k_v2SliderLabel                matlab.ui.control.Label
        k_a2Slider                     matlab.ui.control.Slider
        k_a2SliderLabel                matlab.ui.control.Label
        Slider                         matlab.ui.control.Slider
        SliderLabel                    matlab.ui.control.Label
        k_d1Slider                     matlab.ui.control.Slider
        k_d1SliderLabel                matlab.ui.control.Label
        k_v1Slider                     matlab.ui.control.Slider
        k_v1SliderLabel                matlab.ui.control.Label
        k_a1Slider                     matlab.ui.control.Slider
        k_a1SliderLabel                matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        data % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function loadData(app, event)
            % Allow the user to select a file
            [fileName, ~] = uigetfile({'*.mat', 'MATLAB Files (*.mat)'; ...
                '*.*', 'All Files (*.*)'}, 'Select Data File');

            % Check if the user canceled the operation
            if isequal(fileName,0)
                disp('User canceled the operation');
                return;
            end

            % Load the data from the selected file
            app.data = load(fileName);

% Set needed variable here?

            % Do something with the loaded data, for example, display it
            disp('Data loaded successfully:');
            disp(app.data);
        end

        % Drop down opening function: ParticipantDropDown
        function choose_participant(app, event)
            % Define your array of options
            participantArray = unique(app.data.dataAv.patient);

            % Set the drop-down options
            app.ParticipantDropDown.Items = participantArray;
        end

        % Drop down opening function: PerturbationDirectionDropDown
        function choose_direction(app, event)
            % Define your array of options
            directionArray = unique(app.data.dataAv.pertdir_calc_round_deg);

            % Set the drop-down options
            app.PerturbationDirectionDropDown.Items = directionArray;
        end

        % Drop down opening function: PerturbationMagnitudeDropDown
        function choose_magnitude(app, event)
            % Define your array of options
            magnitudeArray = unique(app.data.dataAv.condition);

            % Set the drop-down options
            app.PerturbationMagnitudeDropDown.Items = magnitudeArray;
        end

        % Button pushed function: PlotButton
        function plot_recon(app, event)
            participant = app.ParticipantDropDown.value;
            direction = app.PerturbationDirectionDropDown.value;
            magnitude = app.PerturbationMagnitudeDropDown.value;
            ReconName = app.ReconstructionTypeDropDown.value;
            addpath('matlabUtilities-master', 'SRMUtilities');

            %% specify CoM kinematics and time window
            atime = dataAv.atime(ind_row,:); % Modify CoM acc with stiction model from Welch and Ting 2009
            a = dataAv.COMAccel_Y(ind_row,:);
            a = stictionTemplate('a',a,'t',atime);
            v = dataAv.COMVelo_Y(ind_row,:);
            d = dataAv.COMPosminusLVDT_Y(ind_row,:);
            % remove basline from CoM kinematics - not needed for "a" due to stiction model
            d = d-mean(d(dataAv.atime(1,:)<-0.1));
            v = v-mean(v(dataAv.atime(1,:)<-0.1));
            % specify time fit by SRM - FROM HOA_PD_SRM.m
            max_time = 1.2;
            min_time = -0.2;
            ind_time = find(dataAv.atime(1,:) > min_time & dataAv.atime(1,:) <= max_time); % adjust window that will be fit by the SRM

            %% flip the CoM kinematic signal depending on pert direction and specify ag/antag EMG
            if direction == 90 %forward pert
                a_ag = -a; v_ag = -v; d_ag = -d;
            %     a_antag = a; v_antag = v; d_antag = d;
                agonist = dataAv.EMG_TA_L_norm(ind_row,:); tag_ag = 'TA'; %ag_norm = dataAv.EMG_TA_L_norm(ind_row,:);
            %     antagonist = dataAv.EMG_MGAS_L_norm(ind_row,:); tag_antag = 'MG'; antag_norm = dataAv.EMG_MGAS_L_norm(ind_row,:);
            elseif direction == 270 %backward pert
                a_ag = a; v_ag = v; d_ag = d;
            %     a_antag = -a; v_antag = -v; d_antag = -d;
            %     antagonist = dataAv.EMG_TA_L_norm(ind_row,:); tag_antag = 'TA'; antag_norm = dataAv.EMG_TA_L_norm(ind_row,:);
                agonist = dataAv.EMG_MGAS_L_norm(ind_row,:); tag_ag = 'MG'; %ag_norm = dataAv.EMG_MGAS_L_norm(ind_row,:);
            end

            %% specify EEG data
            Cz = dataAv.Cz_norm(ind_row,:);
            beta = dataAv.beta_ersp_norm(ind_row,:);

            if strcmp(ReconName,"Recon_Agonist")
                predictors = [a_ag(ind_time); v_ag(ind_time); d_ag(ind_time)]; % SRM predictors
                measuredData = agonist(ind_time); % what the SRM is reconstructing
                Old_Recon = dataAv.Recon_Agonist(ind_row,:); %SRM reconstruction time series
                Old_Gains = dataAv.Gains_Ag(ind_row,:); %SRM reconstruction gains
                Old_fit = dataAv.fit_agonist(ind_row,:); %SRM reconstruction accuracy
                
                New_Recon = assembleChannel(predictors,New_Gains(1:3),New_Gains(end),atime(ind_time));
                
            elseif strcmp(ReconName,"Recon_Agonist_TotalDual_CoM")
                predictors = [a_ag(ind_time); v_ag(ind_time); d_ag(ind_time);...
                    a_ag(ind_time); v_ag(ind_time); d_ag(ind_time)]; % SRM predictors
                measuredData = agonist(ind_time); % what the SRM is reconstructing
                Old_Recon = dataAv.Recon_Agonist_TotalDual_CoM(ind_row,:); %SRM reconstruction time series
                Old_Gains = dataAv.Gains_Ag_TotalDual_CoM(ind_row,:); %SRM reconstruction gains
                Old_fit = dataAv.fit_agonist_TotalDual_CoM(ind_row,:); %SRM reconstruction accuracy
                
                New_Recon = assembleTwoChannels(predictors(1:3,:),New_Gains(1:3),New_Gains(4),...
                    predictors(4:6,:),New_Gains(5:7),New_Gains(end),atime(ind_time));
            
            elseif strcmp(ReconName,"Recon_Agonist_TotalDual_Cz")
                predictors = [a_ag(ind_time); v_ag(ind_time); d_ag(ind_time); -Cz(ind_time)]; % SRM predictors
                measuredData = agonist(ind_time); % what the SRM is reconstructing
                Old_Recon = dataAv.Recon_Agonist_TotalDual_Cz(ind_row,:); %SRM reconstruction time series
                Old_Gains = dataAv.Gains_Ag_TotalDual_Cz(ind_row,:); %SRM reconstruction gains
                Old_fit = dataAv.fit_agonist_TotalDual_Cz(ind_row,:); %SRM reconstruction accuracy
            
                New_Recon = assembleTwoChannels(predictors(1:3,:),New_Gains(1:3),New_Gains(4),...
                    predictors(4,:),New_Gains(5),New_Gains(end),atime(ind_time));
            
            elseif strcmp(ReconName,"Recon_Agonist_TotalDual_beta")
                predictors = [a_ag(ind_time); v_ag(ind_time); d_ag(ind_time); beta(ind_time)]; % SRM predictors
                measuredData = agonist(ind_time); % what the SRM is reconstructing
                Old_Recon = dataAv.Recon_Agonist_TotalDual_beta(ind_row,:); %SRM reconstruction time series
                Old_Gains = dataAv.Gains_Ag_TotalDual_beta(ind_row,:); %SRM reconstruction gains
                Old_fit = dataAv.fit_agonist_TotalDual_beta(ind_row,:); %SRM reconstruction accuracy
                
                New_Recon = assembleTwoChannels(predictors(1:3,:),New_Gains(1:3),New_Gains(4),...
                    predictors(4,:),New_Gains(5),New_Gains(end),atime(ind_time));
                
            elseif strcmp(ReconName,"Recon_Cz")
                predictors = [a_ag(ind_time); v_ag(ind_time); d_ag(ind_time)]; % SRM predictors
                measuredData = -Cz(ind_time); % what the SRM is reconstructing
                Old_Recon = -dataAv.Recon_Cz(ind_row,:); %SRM reconstruction time series
                Old_Gains = dataAv.Gains_Cz(ind_row,:); %SRM reconstruction gains
                Old_fit = dataAv.fit_Cz(ind_row,:); %SRM reconstruction accuracy
                
                New_Recon = assembleChannel(predictors,New_Gains(1:3),New_Gains(end),atime(ind_time));
                
            elseif strcmp(ReconName,"Recon_beta")
                predictors = [a_ag(ind_time); v_ag(ind_time); d_ag(ind_time)]; % SRM predictors
                measuredData = beta(ind_time); % what the SRM is reconstructing
                Old_Recon = dataAv.Recon_Beta(ind_row,:); %SRM reconstruction time series
                Old_Gains = dataAv.Gains_Beta(ind_row,:); %SRM reconstruction gains
                Old_fit = dataAv.fit_beta(ind_row,:); %SRM reconstruction accuracy
                
                New_Recon = assembleChannel(predictors,New_Gains(1:3),New_Gains(end),atime(ind_time));
    
            % elseif strcmp(ReconName,"Recon_Antagonist")
            %     predictors = [a_antag(ind_time); v_antag(ind_time); d_antag(ind_time);....
            %         a_antag(ind_time); v_antag(ind_time); d_antag(ind_time)]; % SRM predictors
            %     measuredData = beta(ind_time); % what the SRM is reconstructing
            %     old_Recon = dataAv.Recon_Beta(ind_row,:); %SRM reconstruction time series
            %     Old_Gains = dataAv.Gains_Beta(ind_row,:); %SRM reconstruction gains
            %     old_fit = dataAv.fit_beta(ind_row,:); %SRM reconstruction accuracy
    
            else
                error('Specified SRMType is not in dataAv');
            end

            %% Calculate R2 and VAF for new fit
            New_fit = [NaN NaN];
            New_fit(1) = rsqr(measuredData',New_Recon');
            New_fit(2) = rsqr_uncentered(measuredData',New_Recon');
            output = table(participant, direction, mag, ReconName, New_Gains, New_fit, New_Recon, Old_Gains, Old_Recon);

            %% plot Old and New SRM Recons
            backLev = mean(measuredData(atime(ind_time) < -0.1),'omitnan');
            title_string = {participant + " Mag: " + string(mag) + " direc: " + string(direction) + " " + ReconName};
            figure

            %% Old Recon
            plotij(2,1,1,1); hold on
            plot(atime(ind_time),measuredData,'b','LineWidth',2) % raw data
            plot(atime(ind_time),Old_Recon,'m','LineWidth',2) % Reconstruction
            if strcmp(ReconName,"Recon_Agonist") || strcmp(ReconName,"Recon_beta") || strcmp(ReconName,"Recon_Cz") % for SRMs w/ only 3 predictors
                plot(atime(ind_time) + Old_Gains(4),...
                    predictors(1,:)*Old_Gains(1)+backLev,...
                    'g-','LineWidth',1.2) % CoM Acc Component
                plot(atime(ind_time)+Old_Gains(4),...
                    predictors(2,:)*Old_Gains(2)+backLev,...
                    'g-','LineWidth',0.5) % CoM Vel Component
                plot(atime(ind_time)+Old_Gains(4),...
                    predictors(3,:)*Old_Gains(3)+backLev,...
                    'g--','LineWidth',0.5) % CoM Disp component
                legend('Data',ReconName,...
                    sprintf('k_{a1}=%1.2g',Old_Gains(1)), sprintf('k_{v1}=%1.2g',Old_Gains(2)), sprintf('k_{d1}=%1.2g',Old_Gains(3)),'Interpreter','none');
                ylabel([tag_ag ' EMG']); xlabel('Time (s)');
            elseif strcmp(ReconName,"Recon_Agonist_TotalDual_CoM") % for SRMs w/ 6 predictors
                plot(atime(ind_time) + Old_Gains(4),...
                    predictors(1,:)*Old_Gains(1)+backLev,...
                    'g-','LineWidth',1.2) % CoM Acc Component
                plot(atime(ind_time)+Old_Gains(4),...
                    predictors(2,:)*Old_Gains(2)+backLev,...
                    'g.','LineWidth',0.4) % CoM Vel Component
                plot(atime(ind_time)+Old_Gains(4),...
                    predictors(3,:)*Old_Gains(3)+backLev,...
                    'g--','LineWidth',0.5) % CoM Disp component
                plot(atime(ind_time)+Old_Gains(end),...
                    predictors(4,:)*Old_Gains(5)+backLev,...
                    'g-','LineWidth',1.2) % CoM Acc - ctx
                plot(atime(ind_time)+Old_Gains(end),...
                    predictors(5,:)*Old_Gains(6)+backLev,...
                    'g.','LineWidth',0.5) % CoM Vel - ctx
                plot(atime(ind_time)+Old_Gains(end),...
                    predictors(6,:)*Old_Gains(7)+backLev,...
                    'g--','LineWidth',0.5)% CoM Disp - ctx
                legend('Data',ReconName,...
                    sprintf('k_{a1}=%1.2g',Old_Gains(1)), sprintf('k_{v1}=%1.2g',Old_Gains(2)), sprintf('k_{d1}=%1.2g',Old_Gains(3)),...
                    sprintf('k_{a2}=%1.2g',Old_Gains(5)), sprintf('k_{v2}=%1.2g',Old_Gains(6)), sprintf('k_{d2}=%1.2g',Old_Gains(7)),'Interpreter','none');
                ylabel([tag_ag ' EMG']); xlabel('Time (s)');
            elseif strcmp(ReconName,"Recon_Agonist_TotalDual_Cz") || strcmp(ReconName,"Recon_Agonist_TotalDual_beta")
                plot(atime(ind_time) + Old_Gains(4),...
                    predictors(1,:)*Old_Gains(1)+backLev,...
                    'g-','LineWidth',1.2) % CoM Acc Component
                plot(atime(ind_time)+Old_Gains(4),...
                    predictors(2,:)*Old_Gains(2)+backLev,...
                    'g.','LineWidth',0.5) % CoM Vel Component
                plot(atime(ind_time)+Old_Gains(4),...
                    predictors(3,:)*Old_Gains(3)+backLev,...
                    'g--','LineWidth',0.5) % CoM Disp component
                plot(atime(ind_time)+Old_Gains(end),...
                    predictors(4,:)*Old_Gains(5)+backLev,...
                    'r-','LineWidth',1) % Ctx component
                legend('Data',ReconName,...
                    sprintf('k_{a1}=%1.2g',Old_Gains(1)), sprintf('k_{v1}=%1.2g',Old_Gains(2)), sprintf('k_{d1}=%1.2g',Old_Gains(3)),...
                    sprintf('k_{Ctx}=%1.2g',Old_Gains(5)),'Interpreter','none')
                ylabel(ReconName); xlabel('Time (s)');
            end
            title("Original Reconstruction" + " R2 = " + num2str(round(Old_fit(1),2)) + " VAF = " + num2str(round(Old_fit(2),2)))
            % xlim([-0.2 1.4]); ylim([-1 1])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Old Reconstruction')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'New Reconstruction')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.Text = 'Save';
            app.k_a1SliderLabel = uilabel(app.UIFigure);
            app.k_a1SliderLabel.Text = 'k_a1';
            app.k_a1Slider = uislider(app.UIFigure);
            app.k_v1SliderLabel = uilabel(app.UIFigure);
            app.k_v1SliderLabel.Text = 'k_v1';
            app.k_v1Slider = uislider(app.UIFigure);
            app.k_d1SliderLabel = uilabel(app.UIFigure);
            app.k_d1SliderLabel.Text = 'k_d1';
            app.k_d1Slider = uislider(app.UIFigure);
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = ' λ_1';
            app.Slider = uislider(app.UIFigure);
            app.k_a2SliderLabel = uilabel(app.UIFigure);
            app.k_a2SliderLabel.Text = 'k_a2';
            app.k_a2Slider = uislider(app.UIFigure);
            app.k_v2SliderLabel = uilabel(app.UIFigure);
            app.k_v2SliderLabel.Text = 'k_v2';
            app.k_v2Slider = uislider(app.UIFigure);
            app.k_d2SliderLabel = uilabel(app.UIFigure);
            app.k_d2SliderLabel.Text = 'k_d2';
            app.k_d2Slider = uislider(app.UIFigure);
            app.Slider_2Label = uilabel(app.UIFigure);
            app.Slider_2Label.Text = ' λ_2';
            app.Slider_2 = uislider(app.UIFigure);
            app.ReconstructionTypeDropDownLabel = uilabel(app.UIFigure);
            app.ReconstructionTypeDropDownLabel.Text = 'Reconstruction Type';
            app.ReconstructionTypeDropDown = uidropdown(app.UIFigure);
            app.ReconstructionTypeDropDown.Items = {'Recon_Agonist', 'Recon_Agonist_TotalDual_CoM', 'Recon_Agonist_TotalDual_Cz', 'Recon_Agonist_TotalDual_beta', 'Recon_Cz', 'Recon_beta'};
            app.ReconstructionTypeDropDown.Value = 'Recon_Agonist';
            app.ParticipantDropDownLabel = uilabel(app.UIFigure);
            app.ParticipantDropDownLabel.Text = 'Participant';
            app.ParticipantDropDown = uidropdown(app.UIFigure);
            app.ParticipantDropDown.DropDownOpeningFcn = createCallbackFcn(app, @choose_participant, true);
            app.PerturbationDirectionDropDownLabel = uilabel(app.UIFigure);
            app.PerturbationDirectionDropDownLabel.Text = 'Perturbation Direction';
            app.PerturbationDirectionDropDown = uidropdown(app.UIFigure);
            app.PerturbationDirectionDropDown.DropDownOpeningFcn = createCallbackFcn(app, @choose_direction, true);
            app.PerturbationMagnitudeDropDownLabel = uilabel(app.UIFigure);
            app.PerturbationMagnitudeDropDownLabel.Text = 'Perturbation Magnitude';
            app.PerturbationMagnitudeDropDown = uidropdown(app.UIFigure);
            app.PerturbationMagnitudeDropDown.DropDownOpeningFcn = createCallbackFcn(app, @choose_magnitude, true);
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @loadData, true);
            app.LoadButton.Text = 'Load';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @plot_recon, true);
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = handfitting_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end